classdef Dobble_compare_only < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UNIVERSIDADPOLITCNICADEMADRIDLabel  matlab.ui.control.Label
        CARGARIMAGENButton        matlab.ui.control.Button
        LIMPIARButton             matlab.ui.control.Button
        COMPARARButton            matlab.ui.control.Button
        ListBox                   matlab.ui.control.ListBox
        ModoButtonGroup           matlab.ui.container.ButtonGroup
        ManualButton              matlab.ui.control.RadioButton
        AutomticoButton           matlab.ui.control.RadioButton
        ResultadocomparacinLabel  matlab.ui.control.Label
        ResultadocomparacionenlabasededatosLabel  matlab.ui.control.Label
        DOBBLEFINDERLabel         matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
        UIAxes2                   matlab.ui.control.UIAxes
        UIAxes3                   matlab.ui.control.UIAxes
        UIAxes4                   matlab.ui.control.UIAxes
        UIAxes5                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ra 
        seleccion1 
        seleccion2        
        Mask_m 
        iconos
        Mask_m2
        iconos2
        carta1
        carta2
        ra1
        resultado_comparacion
        Etiqueta
        i_match
        captura
        guardar1
        guardar2
        cargar1
        imag1
        imag2
        indice_match
        selectedButton 

            
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.indice_match=[];
            app.Mask_m=[]; 
            app.iconos=[]; 
            app.Mask_m2=[]; 
            app.iconos2=[];
            app.carta1=[];
            app.carta2=[];
            app.ra1=[]; 
            app.resultado_comparacion=[];
            app.Etiqueta=[];
            app.i_match=[];
            app.captura=0;
            app.guardar1=0;
            app.guardar2=0;
            app.cargar1=0;
            app.imag1=zeros(2400,6000);
            app.imag2=zeros(1080,1100);
                    
            set(app.UIAxes,'Units','pixels');
            set(app.UIAxes2,'Units','pixels');
            set(app.UIAxes3,'Units','pixels');
            set(app.UIAxes4,'Units','pixels');
            set(app.UIAxes5,'Units','pixels');
            
            imshow(app.imag1,'Parent', app.UIAxes);
            imshow(app.imag2,'Parent', app.UIAxes2);
            imshow(app.imag2,'Parent', app.UIAxes3);           
            imshow(app.imag2,'Parent', app.UIAxes4);
            imshow(app.imag2,'Parent', app.UIAxes5);

            axis(app.UIAxes, 'image');
            axis(app.UIAxes2, 'image');
            axis(app.UIAxes3, 'image');
            axis(app.UIAxes4, 'image');
            axis(app.UIAxes5, 'image');
            
            
            %set(handles.axes5,'Visible','off')
            %set(handles.pushbutton3,'Enable','off')
            % set(handles.pushbutton4,'Enable','off')
            %set(handles.pushbutton5,'Enable','off')
            % set(handles.pushbutton9,'Enable','off')
            if isunix
                if ~any(strcmp("dobble_icons/", strsplit(path, pathsep)))
                    addpath("dobble_icons/");
                end
                if ~any(strcmp("imgs/", strsplit(path, pathsep)))
                    addpath("imgs/");
                end

            else
                if ~any(strcmp("dobble_icons\", strsplit(path, pathsep)))
                    addpath("dobble_icons\");
                end
                if ~any(strcmp("imgs\", strsplit(path, pathsep)))
                    addpath("imgs\");
                end
            end

            
        end

        % Button pushed function: CARGARIMAGENButton
        function CARGARIMAGENButtonPushed(app, event)
            app.selectedButton = app.ModoButtonGroup.SelectedObject;
            app.seleccion1=app.ListBox.Value;
            app.cargar1=1;
            app.ra1=imread( strcat("imgs/",app.seleccion1));
            imshow(app.ra1,'Parent', app.UIAxes)
            drawnow;

            %set(handles.pushbutton8,'Enable','off')
            % set(handles.pushbutton9,'Enable','on')
            
            [app.Mask_m,app.Mask_m2,app.iconos,app.iconos2,app.carta1,app.carta2] = preprocesar(app.ra1,2);
            imshow(app.carta1,'Parent', app.UIAxes2)
            imshow(app.carta2,'Parent', app.UIAxes3)
            drawnow;
            if (app.cargar1==1 )
                %set(handles.pushbutton5,'Enable','on')
            end
            if (app.selectedButton.Text=="Automático")
                [app.i_match,app.indice_match] = comparar(app.carta1,app.carta2,app.Mask_m,app.Mask_m2,app.iconos,app.iconos2);
                imshow(app.i_match, 'Parent', app.UIAxes4);
                drawnow;
                [app.resultado_comparacion,app.Etiqueta] = get_label(app.indice_match,app.i_match,app.iconos);
                imshow(app.resultado_comparacion, 'Parent', app.UIAxes5);
                drawnow;
                app.ra1=[];
                clearvars;
            end

        end

        % Button pushed function: COMPARARButton
        function COMPARARButtonPushed(app, event)
            
            [app.i_match,app.indice_match] = comparar(app.carta1,app.carta2,app.Mask_m,app.Mask_m2,app.iconos,app.iconos2);
            imshow(app.i_match, 'Parent', app.UIAxes4);            
            [app.resultado_comparacion,app.Etiqueta] = get_label(app.indice_match,app.i_match,app.iconos);
            imshow(app.resultado_comparacion, 'Parent', app.UIAxes5);
            app.ra1=[];
            clearvars;
            %set(handles.text7,'String',Etiqueta);
        end

        % Button pushed function: LIMPIARButton
        function LIMPIARButtonPushed(app, event)
            imshow(app.imag1,'Parent', app.UIAxes);
            imshow(app.imag2,'Parent', app.UIAxes2);
            imshow(app.imag2,'Parent', app.UIAxes3);           
            imshow(app.imag2,'Parent', app.UIAxes4);
            imshow(app.imag2,'Parent', app.UIAxes5);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UNIVERSIDADPOLITCNICADEMADRIDLabel = uilabel(app.UIFigure);
            app.UNIVERSIDADPOLITCNICADEMADRIDLabel.Text = 'UNIVERSIDAD POLITÉCNICA DE MADRID';
            app.CARGARIMAGENButton = uibutton(app.UIFigure, 'push');
            app.CARGARIMAGENButton.ButtonPushedFcn = createCallbackFcn(app, @CARGARIMAGENButtonPushed, true);
            app.CARGARIMAGENButton.Text = 'CARGAR IMAGEN';
            app.LIMPIARButton = uibutton(app.UIFigure, 'push');
            app.LIMPIARButton.ButtonPushedFcn = createCallbackFcn(app, @LIMPIARButtonPushed, true);
            app.LIMPIARButton.Text = 'LIMPIAR';
            app.COMPARARButton = uibutton(app.UIFigure, 'push');
            app.COMPARARButton.ButtonPushedFcn = createCallbackFcn(app, @COMPARARButtonPushed, true);
            app.COMPARARButton.Text = 'COMPARAR';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {'towdobble1.tiff', 'towdobble2.tiff', 'towdobble3.tiff', 'towdobble4.tiff', 'towdobble5.tiff', 'towdobble6.tiff', 'towdobble7.tiff', 'towdobble8.tiff', 'towdobble9.tiff', 'towdobble10.tiff', 'towdobble11.tiff', 'towdobble12.tiff', 'towdobble13.tiff', 'towdobble14.tiff', 'towdobble15.tiff', 'towdobble16.tiff', 'towdobble17.tiff', 'towdobble18.tiff', 'towdobble19.tiff', 'towdobble20.tiff', 'towdobble21.tiff', 'towdobble22.tiff', 'towdobble23.tiff', 'towdobble24.tiff', 'towdobble25.tiff', 'towdobble26.tiff', 'towdobble27.tiff', ''};
            app.ListBox.Value = {};
            app.ModoButtonGroup = uibuttongroup(app.UIFigure);
            app.ModoButtonGroup.Title = 'Modo';
            app.ManualButton = uiradiobutton(app.ModoButtonGroup);
            app.ManualButton.Text = 'Manual';
            app.ManualButton.Value = true;
            app.AutomticoButton = uiradiobutton(app.ModoButtonGroup);
            app.AutomticoButton.Text = 'Automático';
            app.ResultadocomparacinLabel = uilabel(app.UIFigure);
            app.ResultadocomparacinLabel.Text = 'Resultado comparación';
            app.ResultadocomparacionenlabasededatosLabel = uilabel(app.UIFigure);
            app.ResultadocomparacionenlabasededatosLabel.Text = 'Resultado comparacion en la base de datos';
            app.DOBBLEFINDERLabel = uilabel(app.UIFigure);
            app.DOBBLEFINDERLabel.Text = 'DOBBLE FINDER';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.UIAxes2 = uiaxes(app.UIFigure);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes3 = uiaxes(app.UIFigure);
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.UIAxes4 = uiaxes(app.UIFigure);
            app.UIAxes4.XTick = [];
            app.UIAxes4.YTick = [];
            app.UIAxes5 = uiaxes(app.UIFigure);
            app.UIAxes5.XTick = [];
            app.UIAxes5.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Dobble_compare_only

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end